classdef MOT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DataLoaderTab                   matlab.ui.container.Tab
        ImagePanel                      matlab.ui.container.Panel
        ImageAxis                       matlab.ui.control.UIAxes
        DataLoadingSettingsPanel        matlab.ui.container.Panel
        FileNameTemplate                matlab.ui.control.EditField
        FileNameTemplateEditFieldLabel  matlab.ui.control.Label
        UpperFrameRange                 matlab.ui.control.NumericEditField
        UpperFrameRangeEditFieldLabel   matlab.ui.control.Label
        LowerFrameRange                 matlab.ui.control.NumericEditField
        LowerFrameRangeEditFieldLabel   matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        SelectDataFolder                matlab.ui.control.Button
        CandidateDetectionTab           matlab.ui.container.Tab
        DetectCandidatesButton          matlab.ui.control.Button
        CandidateAxis                   matlab.ui.control.UIAxes
        CandidateDiscriminationTab      matlab.ui.container.Tab
    end

    
    properties (Access = private)
        fileLocation % Path to the images that are to be loaded
        frameRange % Range of images to load
        nameTemplate % Naming scheme of image files
        frame % When attempting to load a single frame
        csv % Load trimmed gt.txt data
        p %
        candidates %
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectDataFolder
        function SelectDataFolderPushed(app, event)
             app.fileLocation = uigetdir("Select Root Data Folder");
             app.nameTemplate = app.FileNameTemplate.Value;
        end

        % Value changed function: LowerFrameRange
        function LowerFrameRangeValueChanged(app, event)
            app.frameRange(1) = app.LowerFrameRange.Value;
        end

        % Value changed function: UpperFrameRange
        function UpperFrameRangeValueChanged(app, event)
            app.frameRange(2) = app.UpperFrameRange.Value;
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % If frameRange is not specified in the app (i.e. the value is
            % not changed from 0) don't pass it as an input for the parser.
            % If a frameRange is manually specified, pass that input to the
            % parser.
            if app.LowerFrameRange.Value == 0
                app.p = parser(app.fileLocation, app.nameTemplate);
            else
                app.p = parser(app.fileLocation, app.nameTemplate, app.frameRange);
            end

            % Show all loaded images sequentially
            for i = app.p.frameRange(1):app.p.frameRange(2)
                p1 = app.p.file_index(i);
                imshow(p1.frame, 'Parent', app.ImageAxis);
                drawnow;
%                 pause(0.01);
            end
        end

        % Value changed function: FileNameTemplate
        function FileNameTemplateValueChanged(app, event)
            app.nameTemplate = app.FileNameTemplate.Value;
        end

        % Button pushed function: DetectCandidatesButton
        function DetectCandidatesButtonPushed(app, event)
            app.candidates = candidateDetection(app.p);

            for i = 1:length(app.candidates)
                temp = app.candidates{i};
                imshow(temp, 'Parent', app.CandidateAxis);
                drawnow;
                pause(0.1);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataLoaderTab = uitab(app.TabGroup);
            app.DataLoaderTab.Title = 'Data Loader';
            app.DataLoadingSettingsPanel = uipanel(app.DataLoaderTab);
            app.DataLoadingSettingsPanel.Title = 'Data Loading Settings';
            app.SelectDataFolder = uibutton(app.DataLoadingSettingsPanel, 'push');
            app.SelectDataFolder.ButtonPushedFcn = createCallbackFcn(app, @SelectDataFolderPushed, true);
            app.SelectDataFolder.Text = 'Select Data Folder';
            app.LoadDataButton = uibutton(app.DataLoadingSettingsPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.LowerFrameRangeEditFieldLabel = uilabel(app.DataLoadingSettingsPanel);
            app.LowerFrameRangeEditFieldLabel.Text = 'Lower Frame Range';
            app.LowerFrameRange = uieditfield(app.DataLoadingSettingsPanel, 'numeric');
            app.LowerFrameRange.ValueChangedFcn = createCallbackFcn(app, @LowerFrameRangeValueChanged, true);
            app.UpperFrameRangeEditFieldLabel = uilabel(app.DataLoadingSettingsPanel);
            app.UpperFrameRangeEditFieldLabel.Text = 'Upper Frame Range';
            app.UpperFrameRange = uieditfield(app.DataLoadingSettingsPanel, 'numeric');
            app.UpperFrameRange.ValueChangedFcn = createCallbackFcn(app, @UpperFrameRangeValueChanged, true);
            app.FileNameTemplateEditFieldLabel = uilabel(app.DataLoadingSettingsPanel);
            app.FileNameTemplateEditFieldLabel.Text = 'File Name Template';
            app.FileNameTemplate = uieditfield(app.DataLoadingSettingsPanel, 'text');
            app.FileNameTemplate.ValueChangedFcn = createCallbackFcn(app, @FileNameTemplateValueChanged, true);
            app.FileNameTemplate.Value = '%06d';
            app.ImagePanel = uipanel(app.DataLoaderTab);
            app.ImagePanel.Title = 'Image';
            app.ImageAxis = uiaxes(app.ImagePanel);
            app.ImageAxis.XTick = [];
            app.ImageAxis.YTick = [];
            app.ImageAxis.ZTick = [];
            app.CandidateDetectionTab = uitab(app.TabGroup);
            app.CandidateDetectionTab.Title = 'Candidate Detection';
            app.CandidateAxis = uiaxes(app.CandidateDetectionTab);
            title(app.CandidateAxis, 'Title')
            xlabel(app.CandidateAxis, 'X')
            ylabel(app.CandidateAxis, 'Y')
            zlabel(app.CandidateAxis, 'Z')
            app.DetectCandidatesButton = uibutton(app.CandidateDetectionTab, 'push');
            app.DetectCandidatesButton.ButtonPushedFcn = createCallbackFcn(app, @DetectCandidatesButtonPushed, true);
            app.DetectCandidatesButton.Text = 'Detect Candidates';
            app.CandidateDiscriminationTab = uitab(app.TabGroup);
            app.CandidateDiscriminationTab.Title = 'Candidate Discrimination';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MOT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end